classdef zfocus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ZFocusButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZFocusButton
        function ZFocusButtonPushed(app, event)
            global hSI;
            if strcmp(app.ZFocusButton.Text,'Z Focus')
                hSI.hChannels.loggingEnable=false;
                hSI.startGrab
                app.ZFocusButton.Text = 'Stop';
            else
                hSI.abort;
                hSI.hChannels.loggingEnable=true;
                app.ZFocusButton.Text = 'Z Focus';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ZFocusButton = uibutton(app.UIFigure, 'push');
            app.ZFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ZFocusButtonPushed, true);
            app.ZFocusButton.Text = 'Z Focus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zfocus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end